classdef New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationTypePanel             matlab.ui.container.Panel
        SavingAnimationLamp             matlab.ui.control.Lamp
        SavingProgressLabel             matlab.ui.control.Label
        UpdatingDataLamp                matlab.ui.control.Lamp
        UpdatingDataLabel               matlab.ui.control.Label
        UpdateSimulationSettingsButton  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EyxytButton                     matlab.ui.control.RadioButton
        ExxytButton                     matlab.ui.control.RadioButton
        ExytButton                      matlab.ui.control.RadioButton
        SaveToVideoCheckBox             matlab.ui.control.CheckBox
        RunSimulationButton             matlab.ui.control.Button
        ElectricFieldWavePropertiesPanel  matlab.ui.container.Panel
        WavePropagationSpeedEditField   matlab.ui.control.EditField
        WavePropagationSpeedEditFieldLabel  matlab.ui.control.Label
        rEditField_2                    matlab.ui.control.EditField
        rLabel                          matlab.ui.control.Label
        rEditField                      matlab.ui.control.EditField
        rEditFieldLabel                 matlab.ui.control.Label
        ImportantNote00willbothbesetas1tomakethesimulationpossibleLabel  matlab.ui.control.Label
        SimulationPropertiesPanel       matlab.ui.container.Panel
        TotalSimulationTimeEditField    matlab.ui.control.EditField
        TotalSimulationTimeEditFieldLabel  matlab.ui.control.Label
        tEditField_3                    matlab.ui.control.EditField
        Ctxmustbe05Label_2              matlab.ui.control.Label
        tEditField_2                    matlab.ui.control.EditField
        tEditField_2Label               matlab.ui.control.Label
        xyEditField_2                   matlab.ui.control.EditField
        xyEditField_2Label              matlab.ui.control.Label
        AreaBoundryConditionsPanel      matlab.ui.container.Panel
        BCTypeSwitch                    matlab.ui.control.Switch
        BCTypeSwitchLabel               matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.EditField
        WidthEditFieldLabel             matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.EditField
        LengthEditFieldLabel            matlab.ui.control.Label
        PointChargePropertiesPanel      matlab.ui.container.Panel
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        AmplitudeCEditField             matlab.ui.control.EditField
        AmplitudeCEditFieldLabel        matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditFieldLabel                 matlab.ui.control.Label
        ElectricFieldWaveSimulatorLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        currmode = "t";
        ranbefore = false;
        saving = false;
        LengthX = 10;
        WidthY = 10;
        deltaPosition = 0.05;
        numSamplesX = 200;
        numSamplesY = 200;
        TotalTime = 15;
        deltaTime = 0.0125;
        numSamplesTime= 1200;
        t = linspace(0, 15, 1200);
        C = 1; % Wave Probagation speed
        CFL = 1 * (0.0125/0.05);
        U_current ;
        U_minus1;
        U_plus1;
        E_X ;
        E_Y;
        E_t;
        Point_x = 100;
        Point_y = 100;
        SourceAmplitude = 40;
        SourceFreq = 0.75;
        SourceOmage = (0.75 * 2 * pi) ;
        SourceOutput = zeros(1,1200)
    end

    methods (Access = private)

        function [] = InitFields(app)
            app.U_current = zeros(app.numSamplesX, app.numSamplesY);
            app.U_minus1= zeros(app.numSamplesX, app.numSamplesY);
            app.U_plus1= zeros(app.numSamplesX, app.numSamplesY);
            SourceFunction = @(time) (app.deltaTime^2) * app.SourceAmplitude * sin(app.SourceOmage * time);
            SoureOutput = arrayfun(SourceFunction, app.t);
            %E_x loop
            for tIndex= 1 : app.numSamplesTime - 1
                app.U_current(1,:) = 0;
                app.U_current(end,:) = 0;

                % Saving N+1 and N-1 Values
                app.U_minus1 = app.U_current;
                app.U_current = app.U_plus1;

                % Value Due to Source
                app.U_current(app.Point_x, app.Point_y) = SoureOutput(tIndex);

                for xIndex= 2 : app.numSamplesX- 1
                    for yIndex= 2 : app.numSamplesY- 1
                        app.U_plus1(xIndex, yIndex) = ...
                            2 * app.U_current (xIndex, yIndex) - ...
                            app.U_minus1(xIndex, yIndex) + ...
                            app.CFL ^ 2 * ...
                            ( app.U_current(xIndex+1, yIndex) + app.U_current(xIndex, yIndex+1) + ...
                            app.U_current(xIndex-1, yIndex) + app.U_current(xIndex, yIndex-1) - ...
                            4 * app.U_current(xIndex, yIndex) );
                    end
                end

                app.E_X(:,:,tIndex) = app.U_current;
            end
            app.U_current = zeros(app.numSamplesX, app.numSamplesY);
            app.U_minus1= zeros(app.numSamplesX, app.numSamplesY);
            app.U_plus1= zeros(app.numSamplesX, app.numSamplesY);
            %E_y loop
            for tIndex= 1 : app.numSamplesTime - 1
                app.U_current(:, 1) = 0;
                app.U_current(:, end) = 0;

                % Saving N+1 and N-1 Values
                app.U_minus1 = app.U_current;
                app.U_current = app.U_plus1;

                % Value Due to Source
                app.U_current(app.Point_x, app.Point_y) = SoureOutput(tIndex);

                for xIndex= 2 : app.numSamplesX- 1
                    for yIndex= 2 : app.numSamplesY- 1
                        app.U_plus1(xIndex, yIndex) = ...
                            2 * app.U_current (xIndex, yIndex) - ...
                            app.U_minus1(xIndex, yIndex) + ...
                            app.CFL ^ 2 * ...
                            ( app.U_current(xIndex+1, yIndex) + app.U_current(xIndex, yIndex+1) + ...
                            app.U_current(xIndex-1, yIndex) + app.U_current(xIndex, yIndex-1) - ...
                            4 * app.U_current(xIndex, yIndex) );
                    end
                end

                app.E_Y(:,:,tIndex) = app.U_current;
            end

            %Total E
            for tIndex= 1 : app.numSamplesTime - 1
                app.E_t(:,:,tIndex) = (app.E_Y(:,:,tIndex) .^ 2 + app.E_X(:,:,tIndex) .^ 2) .^ 0.5;
            end
        end

        function [] = save_Et(app)
            if (app.currmode == "t")
                Eused = app.E_t;
            elseif (app.currmode == "x")
                Eused = app.E_X;

            elseif (app.currmode == "y")
                Eused = app.E_Y;
            end
            app.SavingAnimationLamp.Color = "red";
            app.RunSimulationButton.Enable = "off";
            app.UpdateSimulationSettingsButton.Enable = "off";
            drawnow;

            x = linspace(0,app.LengthX, app.numSamplesX);
            y = linspace(0,app.WidthY, app.numSamplesY);
            
            f = figure;
            set(f, 'Visible', 'on');
            set(f, 'Renderer', 'OpenGl');
            figureWidth = 1800; % Adjust as needed
            figureHeight = 900; % Adjust as needed
            set(gcf, 'Position', [50, 50, figureWidth, figureHeight]);
            myFrames = cell(app.numSamplesTime,1);
            myFrames(:) = {zeros(figureHeight, figureWidth, 3, 'uint8')};
            colorMap = cell(app.numSamplesTime,1);
            colorMap(:) = {zeros(256, 3)};
            % Now combine these to make the array of structures.
            myMovie = struct('cdata', myFrames, 'colormap', colorMap);
            minValue = min(Eused(:));
            maxValue = max(Eused(:));
            subplot(1,2,1);
            img121 =imagesc(x,y,Eused(:,:,1)'); colorbar; clim([minValue, maxValue]); colormap("jet");
            title("|E(x,y,t)| Heatmap")
            xlabel('X');
            ylabel('Y');
            axis square;
            subplot(1,2,2);
            mesh122 = mesh(x,y,Eused(:,:,1)'); colorbar; clim([minValue, maxValue]); colormap("jet");
            title("|E(x,y,t)| 3D-plot")
            xlabel('X');
            ylabel('Y');
            zlabel('|E(t)|');
            axis([0 app.LengthX 0 app.WidthY minValue maxValue]);
            axis vis3d;
            set(f, 'CloseRequestFcn', ' '); % Disables the 'x' button

            for tIndex= 1 : app.numSamplesTime - 1
                thisdata = Eused(:,:,tIndex)';
                img121.CData = thisdata;
                mesh122.CData = thisdata;
                mesh122.ZData = thisdata;
                thisFrame = getframe(f);
                myMovie(tIndex) = thisFrame;
            end
             set(f, 'CloseRequestFcn', 'closereq'); % Re-enables the 'x' button
              close(f);

            d = string(datetime("now", "Format","dd_MMM_uuuu_HH_mm_ss"));
            folderPath = fullfile(pwd, 'Videos');  % Create full path relative to current script
            if ~exist(folderPath, 'dir')
                mkdir(folderPath);  % Create the folder if it doesn't exist
            end
            frameRate = app.numSamplesTime / app.TotalTime;  
            VidName = fullfile(folderPath, 'Project ' + d + '.mp4');
            vidObj = VideoWriter(VidName, 'MPEG-4');
            vidObj.Quality = 100;  % Adjust quality as needed
            
            vidObj.set("FrameRate", frameRate); 
            open(vidObj);
	            for frameNumber = 1 : app.numSamplesTime - 1 
	               writeVideo(vidObj, myMovie(frameNumber));
	            end
	            close(vidObj);

            app.SavingAnimationLamp.Color= "green";
            app.UpdateSimulationSettingsButton.Enable = "on";
            app.RunSimulationButton.Enable = "on";
            drawnow;
        end

        function [] = PlotEt(app)
            app.RunSimulationButton.Enable = "off";
            app.UpdateSimulationSettingsButton.Enable = "off";
            drawnow;
            if (app.currmode == "t")
                Eused = app.E_t;
            elseif (app.currmode == "x")
                Eused = app.E_X;

            elseif (app.currmode == "y")
                Eused = app.E_Y;
            end

            x = linspace(0,app.LengthX, app.numSamplesX);
            y = linspace(0,app.WidthY, app.numSamplesY);
            maxValue = max(Eused(:));
            minValue = min(Eused(:));
            img121 =imagesc(app.UIAxes2,x,y,Eused(:,:,1)');
            colorbar(app.UIAxes2);
            clim(app.UIAxes2,[minValue, maxValue]);
            colormap(app.UIAxes2, "jet");
            axis(app.UIAxes2, 'equal');

            mesh122 = mesh(app.UIAxes,x,y,Eused(:,:,1)');
            colorbar(app.UIAxes);
            clim(app.UIAxes,[minValue, maxValue]);
            colormap(app.UIAxes, "jet");
            axis(app.UIAxes, [0 app.LengthX 0 app.WidthY minValue maxValue]);
            axis(app.UIAxes, 'vis3d');
            framePause = app.TotalTime/ app.numSamplesTime; 
            for tIndex= 1 : app.numSamplesTime - 1
                % clf;
                thisdata = Eused(:,:,tIndex)';
                img121.CData = thisdata;
                mesh122.CData = thisdata;
                mesh122.ZData = thisdata;
                pause(framePause);
            end
            app.RunSimulationButton.Enable = "on";
                        app.UpdateSimulationSettingsButton.Enable = "on";

            drawnow;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
                    msg = "Are you sure you want to start simulation? It cannot be stopped until it is completed. " + ...
                        "It will last as long as the specified time is if recording is not enabled.";

        selection = uiconfirm(app.UIFigure,msg,"Run Simulation?","Interpreter","tex");
        
        
        switch selection
            case 'OK'
                if (app.saving)
                    app.save_Et();
                else
                    app.PlotEt();
                end
            case 'Cancel'
                return
        end


        end

        % Value changed function: SaveToVideoCheckBox
        function SaveToVideoCheckBoxValueChanged(app, event)
            value = app.SaveToVideoCheckBox.Value;
            if (value == true)
                msgbox("Saving will cause the output to open on a popup window. Please do not resize or close the window during the animation." ...
                    ,"Saving Enabled", "warn");
            end
            app.saving = value;
            app.U_current = zeros(app.numSamplesX, app.numSamplesY);
            app.U_minus1= zeros(app.numSamplesX, app.numSamplesY);
            app.U_plus1= zeros(app.numSamplesX, app.numSamplesY);
        end

        % Button pushed function: UpdateSimulationSettingsButton
        function UpdateSimulationSettingsButtonPushed(app, event)
        app.UpdatingDataLamp.Color = "red";
        app.RunSimulationButton.Enable = "off";
        app.UpdateSimulationSettingsButton.Enable = "off";
            drawnow;

            app.LengthX = str2double(app.LengthEditField.Value);
        app.WidthY= str2double(app.WidthEditField.Value);
        app.deltaPosition = str2double(app.xyEditField_2.Value);
        app.numSamplesX = fix(app.LengthX/app.deltaPosition);
        app.numSamplesY = fix(app.WidthY/app.deltaPosition);
                display(app.numSamplesY);
                display(app.numSamplesX);

        app.TotalTime = str2double(app.TotalSimulationTimeEditField.Value);
        app.deltaTime = str2double(app.tEditField_2.Value);
        app.numSamplesTime= fix(app.TotalTime/app.deltaTime);
        app.t = linspace(0, app.TotalTime, app.numSamplesTime);
        mu = str2double(app.rEditField_2.Value);
        eps = str2double(app.rEditField.Value);
        app.C = 1/(mu*eps);
        app.WavePropagationSpeedEditField.Value = string(app.C); % Wave Probagation speed
        app.CFL = app.C  * (app.deltaTime/app.deltaPosition);
        app.tEditField_3.Value = string(app.CFL);
        app.U_current = zeros(app.numSamplesX, app.numSamplesY);
        app.U_minus1= zeros(app.numSamplesX, app.numSamplesY);
        app.U_plus1= zeros(app.numSamplesX, app.numSamplesY);
        app.E_X = zeros(app.numSamplesX, app.numSamplesY, app.numSamplesTime);
        app.E_Y = zeros(app.numSamplesX, app.numSamplesY, app.numSamplesTime);
        app.E_t = zeros(app.numSamplesX, app.numSamplesY, app.numSamplesTime);
        app.Point_x = fix(str2double(app.XEditField.Value)/app.deltaPosition);
        app.Point_y = fix(str2double(app.YEditField.Value)/app.deltaPosition);
        app.SourceAmplitude = str2double(app.AmplitudeCEditField.Value);
        app.SourceFreq = str2double(app.FrequencyHzEditField.Value); 
        app.SourceOmage = (app.SourceFreq * 2 * pi);    
        msg = "Are you sure you want to update EF values? " + ...
            " CFL: " + string(app.CFL) + "" + ...
            " & \Deltax \Deltay, \Deltat: "  + string(app.deltaPosition) + ", "+ string(app.deltaTime) + "" + ...
            " NumSamples x, y, t: " + string(app.numSamplesX) + ", " + string(app.numSamplesY) + ", " + string(app.numSamplesTime);

        selection = uiconfirm(app.UIFigure,msg,"Update Values?","Interpreter","tex");
        switch selection
            case 'OK'
                app.InitFields();
                app.UpdatingDataLamp.Color = "green";
                app.RunSimulationButton.Enable = "on";
                                            app.UpdateSimulationSettingsButton.Enable = "on";

                drawnow;
                app.ranbefore = true;
            case 'Cancel'
                if (app.ranbefore)
                    app.UpdatingDataLamp.Color = "green";
                    app.RunSimulationButton.Enable = "on";
                                                app.UpdateSimulationSettingsButton.Enable = "on";

                    drawnow;
                else
                    app.UpdatingDataLamp.Color = "white";
                    app.RunSimulationButton.Enable = "off";
                    app.UpdateSimulationSettingsButton.Enable = "on";

                    drawnow;
                end
                return
        end


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            t = selectedButton.Text;
            if (t == "|E(x,y,t)|")
                app.currmode = "t";
            elseif (t == "|Ex(x,y,t)|")
                app.currmode = "x";

            elseif (t == "|Ey(x,y,t)|")
                app.currmode = "y";

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '|E(x,y,t)| 3d Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '|E(x,y,t)| Colormap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ElectricFieldWaveSimulatorLabel = uilabel(app.UIFigure);
            app.ElectricFieldWaveSimulatorLabel.Text = 'Electric Field Wave Simulator';
            app.PointChargePropertiesPanel = uipanel(app.UIFigure);
            app.PointChargePropertiesPanel.Title = 'Point Charge Properties';
            app.XEditFieldLabel = uilabel(app.PointChargePropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.PointChargePropertiesPanel, 'text');
            app.XEditField.Value = '5';
            app.FrequencyHzEditFieldLabel = uilabel(app.PointChargePropertiesPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.PointChargePropertiesPanel, 'text');
            app.FrequencyHzEditField.Value = '0.75';
            app.AmplitudeCEditFieldLabel = uilabel(app.PointChargePropertiesPanel);
            app.AmplitudeCEditFieldLabel.Text = 'Amplitude (C)';
            app.AmplitudeCEditField = uieditfield(app.PointChargePropertiesPanel, 'text');
            app.AmplitudeCEditField.Value = '40';
            app.YEditFieldLabel = uilabel(app.PointChargePropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PointChargePropertiesPanel, 'text');
            app.YEditField.Value = '5';
            app.AreaBoundryConditionsPanel = uipanel(app.UIFigure);
            app.AreaBoundryConditionsPanel.Title = 'Area Boundry Conditions';
            app.LengthEditFieldLabel = uilabel(app.AreaBoundryConditionsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.AreaBoundryConditionsPanel, 'text');
            app.LengthEditField.Value = '10';
            app.WidthEditFieldLabel = uilabel(app.AreaBoundryConditionsPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.AreaBoundryConditionsPanel, 'text');
            app.WidthEditField.Value = '10';
            app.BCTypeSwitchLabel = uilabel(app.AreaBoundryConditionsPanel);
            app.BCTypeSwitchLabel.Enable = 'off';
            app.BCTypeSwitchLabel.Text = 'B.C. Type';
            app.BCTypeSwitch = uiswitch(app.AreaBoundryConditionsPanel, 'slider');
            app.BCTypeSwitch.Items = {'PEC', 'Absorbing'};
            app.BCTypeSwitch.Enable = 'off';
            app.BCTypeSwitch.Value = 'PEC';
            app.SimulationPropertiesPanel = uipanel(app.UIFigure);
            app.SimulationPropertiesPanel.Title = 'Simulation Properties';
            app.xyEditField_2Label = uilabel(app.SimulationPropertiesPanel);
            app.xyEditField_2Label.Text = 'Δx & Δy';
            app.xyEditField_2 = uieditfield(app.SimulationPropertiesPanel, 'text');
            app.xyEditField_2.Value = '0.05';
            app.tEditField_2Label = uilabel(app.SimulationPropertiesPanel);
            app.tEditField_2Label.Text = 'Δt ';
            app.tEditField_2 = uieditfield(app.SimulationPropertiesPanel, 'text');
            app.tEditField_2.Value = '0.0125';
            app.Ctxmustbe05Label_2 = uilabel(app.SimulationPropertiesPanel);
            app.Ctxmustbe05Label_2.Text = '[C * (Δt/Δx)], must be <0.5)';
            app.tEditField_3 = uieditfield(app.SimulationPropertiesPanel, 'text');
            app.tEditField_3.Editable = 'off';
            app.tEditField_3.Value = '0.5';
            app.TotalSimulationTimeEditFieldLabel = uilabel(app.SimulationPropertiesPanel);
            app.TotalSimulationTimeEditFieldLabel.Text = {'Total '; 'Simulation'; 'Time'};
            app.TotalSimulationTimeEditField = uieditfield(app.SimulationPropertiesPanel, 'text');
            app.TotalSimulationTimeEditField.Value = '15';
            app.ElectricFieldWavePropertiesPanel = uipanel(app.UIFigure);
            app.ElectricFieldWavePropertiesPanel.Title = 'Electric Field Wave Properties';
            app.ImportantNote00willbothbesetas1tomakethesimulationpossibleLabel = uilabel(app.ElectricFieldWavePropertiesPanel);
            app.ImportantNote00willbothbesetas1tomakethesimulationpossibleLabel.Text = {'Important Note:'; 'μ0 & ε0 will both be set as "1" to '; 'make the simulation possible.'};
            app.rEditFieldLabel = uilabel(app.ElectricFieldWavePropertiesPanel);
            app.rEditFieldLabel.Text = 'εr';
            app.rEditField = uieditfield(app.ElectricFieldWavePropertiesPanel, 'text');
            app.rEditField.Value = '1';
            app.rLabel = uilabel(app.ElectricFieldWavePropertiesPanel);
            app.rLabel.Text = 'μr';
            app.rEditField_2 = uieditfield(app.ElectricFieldWavePropertiesPanel, 'text');
            app.rEditField_2.Value = '1';
            app.WavePropagationSpeedEditFieldLabel = uilabel(app.ElectricFieldWavePropertiesPanel);
            app.WavePropagationSpeedEditFieldLabel.Text = 'Wave Propagation Speed';
            app.WavePropagationSpeedEditField = uieditfield(app.ElectricFieldWavePropertiesPanel, 'text');
            app.WavePropagationSpeedEditField.Editable = 'off';
            app.WavePropagationSpeedEditField.Value = '1';
            app.SimulationTypePanel = uipanel(app.UIFigure);
            app.SimulationTypePanel.Title = 'Simulation Type';
            app.RunSimulationButton = uibutton(app.SimulationTypePanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Enable = 'off';
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SaveToVideoCheckBox = uicheckbox(app.SimulationTypePanel);
            app.SaveToVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveToVideoCheckBoxValueChanged, true);
            app.SaveToVideoCheckBox.Text = 'Save To Video';
            app.ButtonGroup = uibuttongroup(app.SimulationTypePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ExytButton = uiradiobutton(app.ButtonGroup);
            app.ExytButton.Text = '|E(x,y,t)|';
            app.ExytButton.Value = true;
            app.ExxytButton = uiradiobutton(app.ButtonGroup);
            app.ExxytButton.Text = '|Ex(x,y,t)|';
            app.EyxytButton = uiradiobutton(app.ButtonGroup);
            app.EyxytButton.Text = '|Ey(x,y,t)|';
            app.UpdateSimulationSettingsButton = uibutton(app.SimulationTypePanel, 'push');
            app.UpdateSimulationSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSimulationSettingsButtonPushed, true);
            app.UpdateSimulationSettingsButton.Text = 'Update Simulation Settings';
            app.UpdatingDataLabel = uilabel(app.SimulationTypePanel);
            app.UpdatingDataLabel.Text = 'Updating Data...';
            app.UpdatingDataLamp = uilamp(app.SimulationTypePanel);
            app.SavingProgressLabel = uilabel(app.SimulationTypePanel);
            app.SavingProgressLabel.Text = 'Saving Animation...';
            app.SavingAnimationLamp = uilamp(app.SimulationTypePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end